classdef testapp1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        runButton  matlab.ui.control.Button
        Label      matlab.ui.control.Label
    end

    
    properties (Access = public)
        version = "1.0.0" % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            url = 'https://api.github.com/repos/keitaroyamada/test_matlab/releases/latest';
            try
                releaseInfo = webread(url);
                verInfo = split(releaseInfo.tag_name,{'v','.'});
                curVer = split(app.version,".");
                isLatest = true;
                if str2double(verInfo{2})>str2double(curVer{1})
                    isLatest=false;
                elseif str2double(verInfo{3})>str2double(curVer{2})
                    isLatest=false;
                    elseif str2double(verInfo{4})>str2double(curVer{3})
                    isLatest=false;
                end
                
                q = uiconfirm(app.UIFigure,"ssssssssss","check","Options",{'Get','OK'})

                if q=="Get"
                    web('https://github.com/keitaroyamada/test_matlab/releases/', '-browser');
                end
                disp(isLatest);
            catch e
                fprintf('エラーが発生しました: %s\n', e.message);
            end
        end

        % Button pushed function: runButton
        function runButtonPushed(app, event)
            text = "hello world!!";
            app.Label.Text = text;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '...';
            app.runButton = uibutton(app.UIFigure, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Text = 'run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testapp1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end